classdef HeterogeneousArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        InteractiveListMenu      structeditor.component.InteractiveListMenu
        SelectmetadatatypeLabel  matlab.ui.control.Label
        ControlPanel             matlab.ui.container.Panel
        TypeDropDown             matlab.ui.control.DropDown
        ItemDropDown             matlab.ui.control.DropDown
        EditItemLabel            matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
    end

    properties (Access = private)
        ItemNodeList matlab.ui.container.TreeNode % Description

        UIControlContainers (1,:) structeditor.UIControlContainer
        ContainerTypeMap (1,:) string
        StructTypeMap (1,:) string
    end


    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end
    
    methods (Access = private)
        
        function onSelectedItemChanged(app, src, evt)

            prevIdx = app.CurrentIdx;

            if prevIdx == evt.NewIndex
                disp('returning')
                return
            end

            previousType = app.StructTypeMap(prevIdx);
            newType = app.StructTypeMap(evt.NewIndex);

            prevContainerIdx = app.getContainerTypeIndex(previousType);
            newContainerIdx = app.getContainerTypeIndex(newType);
            
            updatedData = app.UIControlContainers(prevContainerIdx).Data;
            app.Data{prevIdx} = updatedData;

            data = app.Data{evt.NewIndex};
            app.UIControlContainers(newContainerIdx).Data = data;

            app.UIControlContainers(prevContainerIdx).Visible = 'off';
            app.UIControlContainers(newContainerIdx).Visible = 'on';

            app.CurrentIdx = evt.NewIndex;
        end

        function onItemAdded(app, src, evt)

            if ~isempty(app.UIControlContainers)
                previousType = app.StructTypeMap(app.CurrentIdx);
                prevContainerIdx = app.getContainerTypeIndex(previousType);
                updatedData = app.UIControlContainers(prevContainerIdx).Data;
                app.Data{app.CurrentIdx} = updatedData;
            end

            % Todo: get selected type...
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                selectedType = app.TypeDropDown.Value;
                newStruct = app.DefaultItem.getType(selectedType);
            else
                selectedType = string(missing);
                newStruct = app.DefaultItem;
            end
            
            app.Data{end+1} = newStruct;
            app.CurrentIdx = numel(app.Data);
            app.StructTypeMap(app.CurrentIdx) = selectedType;
            
            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    H = structeditor.UIControlContainer(app.ControlPanel, newStruct);
                    app.UIControlContainers(end+1) = H;
                    app.ContainerTypeMap(end+1) = selectedType;
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end
            
            
            disp(newStruct)
            disp('data post:')
            app.Data{:}
            app.UIControlContainers(idx).Data = newStruct;
            
            % Add itemtype in parenthesis.
            name = app.InteractiveListMenu.getItemName(app.CurrentIdx);
            name  = sprintf('%s (%s)', name, selectedType);
            app.InteractiveListMenu.renameItem(app.CurrentIdx, name)
        end

        function changeContainerType(app, type)
            

        end

        function idx = getContainerTypeIndex(app, typeName)
            idx = find( strcmp(app.ContainerTypeMap, typeName) );
        end
    end

    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.DefaultItem = {}
            end

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;

            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                app.TypeDropDown.Items = app.DefaultItem.TypeNames;
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data{i}.(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data{i} );
                else
                    itemName = '';
                end
                
                app.InteractiveListMenu.addItem(itemName)
            end

            if ~isempty(  app.Data )

                H = structeditor.UIControlContainer(app.ControlPanel, app.Data{1});
                app.UIControlContainers = H;

                app.CurrentIdx = 1;
            else
                app.CurrentIdx = 0;
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditItemLabel = uilabel(app.GridLayout);
            app.EditItemLabel.Text = 'Edit Item';
            app.ItemDropDown = uidropdown(app.GridLayout);
            app.ItemDropDown.Items = {'<Create New Item>', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.ItemDropDown.Value = '<Create New Item>';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4'};
            app.TypeDropDown.Value = 'Type 1';
            app.ControlPanel = uipanel(app.GridLayout);
            app.SelectmetadatatypeLabel = uilabel(app.GridLayout);
            app.SelectmetadatatypeLabel.Text = 'Select metadata type';
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeterogeneousArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end